classdef TrafficLightArduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        AutoPanel     matlab.ui.container.Panel
        Label         matlab.ui.control.Label
        ManualPanel   matlab.ui.container.Panel
        GREENButton   matlab.ui.control.Button
        YELLOWButton  matlab.ui.control.Button
        REDButton     matlab.ui.control.Button
        EditField     matlab.ui.control.NumericEditField
        ManualButton  matlab.ui.control.StateButton
        AutoButton    matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value)
            global a;
            global Auto ;
            Auto = false;
            global delay ;
            delay = 0;
             a = arduino('COM3','Uno');
            configurePin(a,'D7','DigitalOutput');
            configurePin(a,'D8','DigitalOutput');
            configurePin(a,'D9','DigitalOutput');
            
            writeDigitalPin(a,'D7',0);
            writeDigitalPin(a,'D8',0);
            writeDigitalPin(a,'D9',0);
        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D7',1);
            writeDigitalPin(a,'D8',0);
            writeDigitalPin(a,'D9',0);
        end

        % Button pushed function: YELLOWButton
        function YELLOWButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D7',0);
            writeDigitalPin(a,'D8',1);
            writeDigitalPin(a,'D9',0);
        end

        % Button pushed function: GREENButton
        function GREENButtonPushed(app, event)
            global a;
            writeDigitalPin(a,'D7',0);
            writeDigitalPin(a,'D8',0);
            writeDigitalPin(a,'D9',1);
        end

        % Value changed function: AutoButton
        function AutoButtonValueChanged(app, event)
            %value = app.AutoButton.Value;
           global a;
global Auto;
global delay;
Auto = true;

% Assuming you have defined app somewhere in your code
app.AutoPanel.Enable = "on";
app.ManualPanel.Enable = "off";

while Auto
    delay = app.EditField.Value;

    % Red light
    writeDigitalPin(a, 'D7', 1);
    writeDigitalPin(a, 'D8', 0);
    writeDigitalPin(a, 'D9', 0);
    for i = delay:-1:1
        app.Label.Text = num2str(i);
        pause(1);
    end

    % Yellow light
    writeDigitalPin(a, 'D7', 0);
    writeDigitalPin(a, 'D8', 1);
    writeDigitalPin(a, 'D9', 0);
    for i = delay:-1:1
        app.Label.Text = num2str(i);
        pause(1);
    end

    % Green light
    writeDigitalPin(a, 'D7', 0);
    writeDigitalPin(a, 'D8', 0);
    writeDigitalPin(a, 'D9', 1);
    for i = delay:-1:1
        app.Label.Text = num2str(i);
        pause(1);
    end
end
    
        end

        % Value changed function: ManualButton
        function ManualButtonValueChanged(app, event)
            %value = app.ManualButton.Value;
            global delay ;
            global Auto ;
            Auto = false;
            app.AutoPanel.Enable = "off"; 
            app.ManualPanel.Enable = "on";
            delay=0;
            app.EditField.Value = delay;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutoButton = uibutton(app.UIFigure, 'state');
            app.AutoButton.ValueChangedFcn = createCallbackFcn(app, @AutoButtonValueChanged, true);
            app.AutoButton.Text = 'Auto';
            app.ManualButton = uibutton(app.UIFigure, 'state');
            app.ManualButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualButton.Text = 'Manual';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ManualPanel = uipanel(app.UIFigure);
            app.ManualPanel.Title = 'Manual';
            app.REDButton = uibutton(app.ManualPanel, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.YELLOWButton = uibutton(app.ManualPanel, 'push');
            app.YELLOWButton.ButtonPushedFcn = createCallbackFcn(app, @YELLOWButtonPushed, true);
            app.YELLOWButton.Text = 'YELLOW';
            app.GREENButton = uibutton(app.ManualPanel, 'push');
            app.GREENButton.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREENButton.Text = 'GREEN';
            app.AutoPanel = uipanel(app.UIFigure);
            app.AutoPanel.Title = 'Auto';
            app.Label = uilabel(app.AutoPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrafficLightArduino(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end